classdef testApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ClickPositionLabel   matlab.ui.control.Label
        NumberofSeedsLabel   matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        SizeSlider           matlab.ui.control.Slider
        SizeSliderLabel      matlab.ui.control.Label
        RotationSlider       matlab.ui.control.Slider
        RotationSliderLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
            value = app.RotationSlider.Value;
            app.Label.Text = string(value);
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            rotation = app.RotationSlider.Value;
            size = app.SizeSlider.Value;

            coords = event.IntersectionPoint;
            app.ClickPositionLabel.Text  = string(coords(1) + ",  " + coords(2));

            
%             app.UIAxes.XLim = [0 2*pi];
            n = 0;
            radius = 0.0;
            theta = 0.0;
            hold on;
            while radius < size
                radius = double(sqrt(n));
                theta = n * rotation;
                x_pos = coords(1) + radius*cos(theta);
                y_pos = coords(2) + radius*sin(theta);
                plot(app.UIAxes,x_pos,y_pos, '-ko','MarkerSize',12);
                hold on;
                drawnow;
                n = n + 1;

            end
            hold off;

            
        end

        % Value changed function: SizeSlider
        function SizeSliderValueChanged(app, event)
            value = app.SizeSlider.Value;
            app.Label_2.Text = string(value);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 20];
            app.UIAxes.ZLim = [0 20];
            app.UIAxes.XTick = [0 5 10 15 20];
            app.UIAxes.YTick = [0 5 10 15 20];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RotationSliderLabel = uilabel(app.UIFigure);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.UIFigure);
            app.RotationSlider.Limits = [0 1];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.SizeSliderLabel = uilabel(app.UIFigure);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.UIFigure);
            app.SizeSlider.Limits = [0 10];
            app.SizeSlider.ValueChangedFcn = createCallbackFcn(app, @SizeSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0.0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0.0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.NumberofSeedsLabel = uilabel(app.UIFigure);
            app.NumberofSeedsLabel.Text = 'Number of Seeds:';
            app.ClickPositionLabel = uilabel(app.UIFigure);
            app.ClickPositionLabel.Text = 'Click Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end